classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KhoangphanlyA             matlab.ui.control.EditField
        KhongphnlyEditFieldLabel  matlab.ui.control.Label
        Resultnghiem              matlab.ui.control.EditField
        ResultEditFieldLabel      matlab.ui.control.Label
        Saiso                     matlab.ui.control.EditField
        SaisEditFieldLabel        matlab.ui.control.Label
        SlnlpLabel                matlab.ui.control.Label
        NghimLabel                matlab.ui.control.Label
        solanlap                  matlab.ui.control.EditField
        aLabel                    matlab.ui.control.Label
        khoangphanlyB             matlab.ui.control.EditField
        PHNGPHPTNHGNNGNGHIMCAPHNGTRNHLabel  matlab.ui.control.Label
        TmnghimPanel              matlab.ui.container.Panel
        bLabel                    matlab.ui.control.Label
        Function2                 matlab.ui.control.EditField
        Fuction2Label             matlab.ui.control.Label
        FunctionEditFieldLabel    matlab.ui.control.Label
        Function                  matlab.ui.control.EditField
        Button3_Reset             matlab.ui.control.Button
        ChooseDropDown            matlab.ui.control.DropDown
        ChooseDropDownLabel       matlab.ui.control.Label
        Button2_Callbacks         matlab.ui.control.Button
        Button1_Callbacks         matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1_Callbacks
        function Button1_CallbacksPushed(app, event)
            f=app.Function.Value;
            a=app.KhoangphanlyA.Value;
            a=str2double(a);
            b=app.khoangphanlyB.Value;
            b=str2double(b);
            saiso=app.Saiso.Value;
            saiso=str2double(saiso);
            fp=app.Function2.Value;
             if (~isempty(f) && isempty(fp))
                f = str2func(['@(x)' f]);
             elseif (isempty(f) && ~isempty(fp))
                fp = str2func(['@(x)' fp]);
             else
                msgbox('Số lương hàm hiều hơn số lượng biến của hàm ', 'Error', 'error');
             end 
%             fl=[f '+x'];
%             fl2 = str2func(['@(x)' fl]);
            switch app.ChooseDropDown.Value
                     case 'Chia đôi'
                    [nghiem_chiadoi,solanlap_chiadoi] =chiadoi(f,a,b,saiso);
                    nghiem_chiadoi = num2str(nghiem_chiadoi);
                    solanlap_chiadoi = num2str(solanlap_chiadoi);
                    app.Resultnghiem.Value=nghiem_chiadoi;
                    app.solanlap.Value=solanlap_chiadoi;
                     case 'Lặp'
                    [nghiem_lap,solanlap_lap] = lap(fp,a,b,saiso);
                    nghiem_lap = num2str(nghiem_lap);
                    solanlap_lap = num2str(solanlap_lap);
                    app.Resultnghiem.Value=nghiem_lap;
                    app.solanlap.Value=solanlap_lap;
                     case 'Tuyến tính'
                    [nghiem_tieptuyen,solanlap_tieptuyen] = tieptuyen(f,a,b,saiso);
                    nghiem_tieptuyen = num2str(nghiem_tieptuyen);
                    solanlap_tieptuyen = num2str(solanlap_tieptuyen);
                    app.Resultnghiem.Value=nghiem_tieptuyen;
                    app.solanlap.Value=solanlap_tieptuyen;
            end
        end

        % Button pushed function: Button2_Callbacks
        function Button2_CallbacksPushed(app, event)
                    f=app.Function.Value;
                    f = str2func(['@(x)' f]);
                    disp(f(3))
                    a=app.KhoangphanlyA.Value;
                    a=str2num(a);
                    b=app.khoangphanlyB.Value;
                    b=str2num(b);
                    x=linspace(a,b,30);
                    y=f(x);
                    switch app.ChooseDropDown.Value
                        case 'Chia đôi'
                            plot(app.UIAxes,x,y,'g');
                        case 'Lặp'
                             plot(app.UIAxes,x,y,'r');
                        case 'Tuyến tính'
                             plot(app.UIAxes,x,y,'b'); 
                    end
        end

        % Button pushed function: Button3_Reset
        function Button3_ResetPushed(app, event)
            app.Resultnghiem.Value ='';
            app.solanlap.Value='';
            cla(app.UIAxes,"reset");
            title(app.UIAxes,"Đồ thị tìm nghiệm")
            xlabel(app.UIAxes,"X");
            ylabel(app.UIAxes,"Y");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TmnghimPanel = uipanel(app.UIFigure);
            app.TmnghimPanel.Title = 'Tìm nghiệm';
            app.UIAxes = uiaxes(app.TmnghimPanel);
            title(app.UIAxes, 'Đồ thị tìm nghiệm')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button1_Callbacks = uibutton(app.TmnghimPanel, 'push');
            app.Button1_Callbacks.ButtonPushedFcn = createCallbackFcn(app, @Button1_CallbacksPushed, true);
            app.Button1_Callbacks.Text = 'Find';
            app.Button2_Callbacks = uibutton(app.TmnghimPanel, 'push');
            app.Button2_Callbacks.ButtonPushedFcn = createCallbackFcn(app, @Button2_CallbacksPushed, true);
            app.Button2_Callbacks.Text = 'Draw';
            app.ChooseDropDownLabel = uilabel(app.TmnghimPanel);
            app.ChooseDropDownLabel.Text = 'Choose';
            app.ChooseDropDown = uidropdown(app.TmnghimPanel);
            app.ChooseDropDown.Items = {'Chia đôi', 'Lặp', 'Tuyến tính'};
            app.ChooseDropDown.Value = 'Lặp';
            app.Button3_Reset = uibutton(app.TmnghimPanel, 'push');
            app.Button3_Reset.ButtonPushedFcn = createCallbackFcn(app, @Button3_ResetPushed, true);
            app.Button3_Reset.Text = 'Reset';
            app.Function = uieditfield(app.TmnghimPanel, 'text');
            app.Function.Value = ' x-sin(x)-0.25';
            app.FunctionEditFieldLabel = uilabel(app.TmnghimPanel);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.Fuction2Label = uilabel(app.TmnghimPanel);
            app.Fuction2Label.Text = 'Fuction2';
            app.Function2 = uieditfield(app.TmnghimPanel, 'text');
            app.bLabel = uilabel(app.TmnghimPanel);
            app.bLabel.Text = 'b';
            app.PHNGPHPTNHGNNGNGHIMCAPHNGTRNHLabel = uilabel(app.UIFigure);
            app.PHNGPHPTNHGNNGNGHIMCAPHNGTRNHLabel.Tag = 'title';
            app.PHNGPHPTNHGNNGNGHIMCAPHNGTRNHLabel.Text = 'PHƯƠNG PHÁP TÍNH GẦN ĐÚNG NGHIỆM CỦA PHƯƠNG TRÌNH';
            app.khoangphanlyB = uieditfield(app.UIFigure, 'text');
            app.khoangphanlyB.Value = '2';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.solanlap = uieditfield(app.UIFigure, 'text');
            app.NghimLabel = uilabel(app.UIFigure);
            app.NghimLabel.Text = 'Nghiệm ';
            app.SlnlpLabel = uilabel(app.UIFigure);
            app.SlnlpLabel.Text = 'Số lần lặp';
            app.SaisEditFieldLabel = uilabel(app.UIFigure);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.Saiso = uieditfield(app.UIFigure, 'text');
            app.Saiso.Value = '0.005';
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.Resultnghiem = uieditfield(app.UIFigure, 'text');
            app.KhongphnlyEditFieldLabel = uilabel(app.UIFigure);
            app.KhongphnlyEditFieldLabel.Text = 'Khoảng phân ly ';
            app.KhoangphanlyA = uieditfield(app.UIFigure, 'text');
            app.KhoangphanlyA.Value = '-1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end